classdef main_P4_SCBIO_DNI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout6               matlab.ui.container.GridLayout
        GridLayout3               matlab.ui.container.GridLayout
        num_ent                   matlab.ui.control.Slider
        num_test                  matlab.ui.control.Slider
        GridLayout10              matlab.ui.container.GridLayout
        NmeroentrenamientosLabel  matlab.ui.control.Label
        num_entEditField          matlab.ui.control.NumericEditField
        GridLayout11              matlab.ui.container.GridLayout
        NmerotestsLabel           matlab.ui.control.Label
        num_testEditField         matlab.ui.control.NumericEditField
        GridLayout12              matlab.ui.container.GridLayout
        RUNButton                 matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        GridLayout4               matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        myperceptron_ANDButton    matlab.ui.control.StateButton
        GridLayout7               matlab.ui.container.GridLayout
        myperceptron_XORButton    matlab.ui.control.StateButton
        GridLayout8               matlab.ui.container.GridLayout
        mynn_XORButton            matlab.ui.control.StateButton
        GridLayout9               matlab.ui.container.GridLayout
        matlabperceptronButton    matlab.ui.control.StateButton
        GridLayout13              matlab.ui.container.GridLayout
        ShowInputsButton          matlab.ui.control.Button
    end

    
    methods (Access = private) 
        function graf(app,results)
            SEV=results.SEV;
            S_est=results.S_est;
            plot(app.UIAxes,SEV,'ok','LineWidth',2)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,S_est,'xr','LineWidth',2)
            set(app.UIAxes,'FontSize',12) %# Fix font size of the text in the current axes 
            set(app.UIAxes,'FontWeight','bold')  %# Fix Bold text in the current axes 
            xlabel(app.UIAxes,'Number of test','FontWeight','bold')
            ylabel(app.UIAxes,'Output values','FontWeight','bold')
            axis(app.UIAxes,[-1 length(SEV)+1 -0.1 1.3])
            legend(app.UIAxes,'Correct Values','Perceptron Output')
            %title(app.UIAxes,'Evaluation of Perceptron Ouput for an AND','FontWeight','bold')
            hold(app.UIAxes,'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global modo
            global inputs
            close all
            modo=0;
            inputs=[];
        end

        % Value changed function: myperceptron_ANDButton
        function myperceptron_ANDButtonValueChanged(app, event)
            value = app.myperceptron_ANDButton.Value;
            global modo
            if value==0
                modo=0;
            else
                app.num_ent.Value=5000;
                app.num_entEditField.Value=5000;
                modo=1;
                app.myperceptron_XORButton.Value=0;
                app.mynn_XORButton.Value=0;
                app.matlabperceptronButton.Value=0;
            end
        end

        % Value changed function: myperceptron_XORButton
        function myperceptron_XORButtonValueChanged(app, event)
            value = app.myperceptron_XORButton.Value;
            global modo
            if value==0
                modo=0;
            else
                app.num_ent.Value=5000;
                app.num_entEditField.Value=5000;
                modo=2;
                app.myperceptron_ANDButton.Value=0;
                app.mynn_XORButton.Value=0;
                app.matlabperceptronButton.Value=0;
            end
        end

        % Value changed function: mynn_XORButton
        function mynn_XORButtonValueChanged(app, event)
            value = app.mynn_XORButton.Value;
            global modo
            if value==0
                modo=0;
            else
                modo=3;
                app.myperceptron_XORButton.Value=0;
                app.myperceptron_ANDButton.Value=0;
                app.matlabperceptronButton.Value=0;
            end
        end

        % Value changed function: matlabperceptronButton
        function matlabperceptronButtonValueChanged(app, event)
            value = app.matlabperceptronButton.Value;
            global modo
            if value==0
                modo=0;
            else
                app.num_ent.Value=1000;
                app.num_entEditField.Value=1000;
                modo=4;
                app.myperceptron_XORButton.Value=0;
                app.myperceptron_ANDButton.Value=0;
                app.mynn_XORButton.Value=0;
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            global modo
            global inputs
            cla(app.UIAxes,'reset')
            ent=app.num_entEditField.Value;
            test=app.num_testEditField.Value;
            
            if modo==0
            close all   
            elseif modo==1
                [results,inputs]=myperceptron_AND_DNI(0,ent,test);
                app.graf(results);
                title(app.UIAxes,'Evaluation of Perceptron Ouput for an AND',...
                    'FontWeight','bold')
            elseif modo==2
                [results,inputs]=myperceptron_AND_DNI(1,ent,test);
                app.graf(results);
                title(app.UIAxes,'Evaluation of Perceptron Ouput for an XOR',...
                    'FontWeight','bold')
            elseif modo==3
                [results,inputs]=mynn_XOR_DNI(ent,test);
                %uitable('Data',inputs);
                app.graf(results);
                title(app.UIAxes,'Evaluation of Perceptron Ouput for an XOR',...
                    'FontWeight','bold')
            elseif modo==4
                results=matlabperceptron_DNI(ent,test);
                app.graf(results);
                title(app.UIAxes,'Evaluation of Perceptron Ouput for an XOR',...
                    'FontWeight','bold')
            end
        end

        % Value changed function: num_testEditField
        function num_testEditFieldValueChanged(app, event)
            value = app.num_testEditField.Value;
            app.num_test.Value=value;
        end

        % Value changed function: num_entEditField
        function num_entEditFieldValueChanged(app, event)
            value = app.num_entEditField.Value;
            if value>10000, value=10000; end
            app.num_ent.Value=value;
        end

        % Value changing function: num_test
        function num_testValueChanging(app, event)
            changingValue = event.Value;
            app.num_testEditField.Value=round(changingValue);
        end

        % Value changing function: num_ent
        function num_entValueChanging(app, event)
            changingValue = event.Value;
            app.num_entEditField.Value=round(changingValue);
        end

        % Button pushed function: ShowInputsButton
        function ShowInputsButtonPushed(app, event)
            global inputs
            close all
            fig_table=uifigure("HandleVisibility",'on','Position',[850,100,400,580]);
            uitable(fig_table,'Data',inputs,'Position',[10,10,380,560]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.GridLayout6);
            app.num_ent = uislider(app.GridLayout3);
            app.num_ent.Limits = [0 10000];
            app.num_ent.MajorTicks = [0 2500 5000 7500 10000];
            app.num_ent.MajorTickLabels = {'0', '2500', '5000', '7500', '10000'};
            app.num_ent.ValueChangingFcn = createCallbackFcn(app, @num_entValueChanging, true);
            app.num_ent.Value = 5000;
            app.num_test = uislider(app.GridLayout3);
            app.num_test.ValueChangingFcn = createCallbackFcn(app, @num_testValueChanging, true);
            app.num_test.Value = 20;
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.NmeroentrenamientosLabel = uilabel(app.GridLayout10);
            app.NmeroentrenamientosLabel.Text = ' Número entrenamientos';
            app.num_entEditField = uieditfield(app.GridLayout10, 'numeric');
            app.num_entEditField.Limits = [0 100000];
            app.num_entEditField.ValueChangedFcn = createCallbackFcn(app, @num_entEditFieldValueChanged, true);
            app.num_entEditField.Value = 5000;
            app.GridLayout11 = uigridlayout(app.GridLayout3);
            app.NmerotestsLabel = uilabel(app.GridLayout11);
            app.NmerotestsLabel.Text = ' Número tests';
            app.num_testEditField = uieditfield(app.GridLayout11, 'numeric');
            app.num_testEditField.Limits = [0 100];
            app.num_testEditField.ValueChangedFcn = createCallbackFcn(app, @num_testEditFieldValueChanged, true);
            app.num_testEditField.Value = 20;
            app.GridLayout12 = uigridlayout(app.GridLayout6);
            app.RUNButton = uibutton(app.GridLayout12, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.UIAxes = uiaxes(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.myperceptron_ANDButton = uibutton(app.GridLayout5, 'state');
            app.myperceptron_ANDButton.ValueChangedFcn = createCallbackFcn(app, @myperceptron_ANDButtonValueChanged, true);
            app.myperceptron_ANDButton.Text = 'myperceptron_AND';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.myperceptron_XORButton = uibutton(app.GridLayout7, 'state');
            app.myperceptron_XORButton.ValueChangedFcn = createCallbackFcn(app, @myperceptron_XORButtonValueChanged, true);
            app.myperceptron_XORButton.Text = 'myperceptron_XOR';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.mynn_XORButton = uibutton(app.GridLayout8, 'state');
            app.mynn_XORButton.ValueChangedFcn = createCallbackFcn(app, @mynn_XORButtonValueChanged, true);
            app.mynn_XORButton.Text = 'mynn_XOR';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.matlabperceptronButton = uibutton(app.GridLayout9, 'state');
            app.matlabperceptronButton.ValueChangedFcn = createCallbackFcn(app, @matlabperceptronButtonValueChanged, true);
            app.matlabperceptronButton.Text = 'matlabperceptron';
            app.GridLayout13 = uigridlayout(app.GridLayout4);
            app.ShowInputsButton = uibutton(app.GridLayout13, 'push');
            app.ShowInputsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInputsButtonPushed, true);
            app.ShowInputsButton.Text = 'Show Inputs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_P4_SCBIO_DNI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end